classdef AnalysisTempHyp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectpixelButton_2        matlab.ui.control.Button
        yEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
        DelaycorrectionLabel       matlab.ui.control.Label
        DelaycaliabrationCheckBox  matlab.ui.control.CheckBox
        yLabel                     matlab.ui.control.Label
        xLabel                     matlab.ui.control.Label
        SelectpixelButton          matlab.ui.control.Button
        LoadeddatasetLabel         matlab.ui.control.Label
        TemporalaxisSlider         matlab.ui.control.RangeSlider
        TemporalaxisSliderLabel    matlab.ui.control.Label
        LoadhypercubeButton        matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        HyperMatrix % temporal hypercube
        t0 % original delay axis 
        tt %corrected delay axis

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadhypercubeButton
        function LoadhypercubeButtonPushed(app, event)
             [filename_Hyper, pathname_Hyper] = uigetfile('*.mat', 'Load Hypercube');
             file_tot=[pathname_Hyper,filename_Hyper];
             dir0=pathname_Hyper;
             load(file_tot);
             app.HyperMatrix=double(HyperMatrix); 
             app.t0=t; %original delay axis
             
             %Update file name
             app.LoadeddatasetLabel.Text=filename_Hyper;

             %Plot average intensity
             Averages=mean(app.HyperMatrix,3); 
             imagesc(app.UIAxes, Averages); colormap(app.UIAxes,"gray"); 


        end

        % Button pushed function: SelectpixelButton
        function SelectpixelButtonPushed(app, event)
            axes(app.UIAxes);
            %[x,y]=ginput(1); %cannot be used in AppDesigner!
            % point = datacursormode(app.UIFigure);
            % point.UpdateFcn=@showValue;
            % x = point(1);
            % y = point(2);
            %disp(['X: ', num2str(x), ', Y: ', num2str(y)]);

            %Update coordinates
            app.xLabel.Text=num2str(x);
            app.yLabel.Text=num2str(y);

            %Plot corresponding interferogram
            plot(app.UIAxes2, app.tt, squeeze(app.HyperMatrix(x,y,:)))
          % 
          % function output = showValue(~,event_obj)
          % 
          %    % ~         Currently not used (empty)
          %    % event_obj Object containing event data structure
          %    % output    Data cursor output
          % 
          %    rData  = vecnorm([event_obj.Target.XData; event_obj.Target.YData]);
          %    [~,i]  = min(abs(norm(event_obj.Position) - rData));
          %    output = event_obj.Target.CData(i);
          % 
          % end
        end

        % Value changed function: DelaycaliabrationCheckBox
        function DelaycaliabrationCheckBoxValueChanged(app, event)
            value = app.DelaycaliabrationCheckBox.Value;
            if value
                [filename_axis, pathname_axis] = uigetfile('*.mat', 'Load delay correction');
                file_tot=[pathname_axis,filename_axis];
                dir0=pathname_axis;
                load(file_tot);

                %corrected axis
                app.tt=app.t0+interp1(t_axis, t_delayCorrection, app.t0); 

                %Update label
                app.DelaycorrectionLabel.Text=filename_axis; 

            else
                app.tt=app.t0; %original axis
            end
        end

        % Button pushed function: SelectpixelButton_2
        function SelectpixelButton_2Pushed(app, event)

            %Plot interferogram
            x=app.xEditField.Value;
            y=app.yEditField.Value; 

            if app.DelaycaliabrationCheckBox.Value
                %corrected axis
                app.tt=app.t0+interp1(t_axis, t_delayCorrection, app.t0); 

                %Update label
                app.DelaycorrectionLabel.Text=filename_axis; 

            else
                app.tt=app.t0; %original axis
            end

            interferogram=squeeze(app.HyperMatrix(x,y,:))
            plot(app.UIAxes2, app.tt, interferogram);

            %Plot spectrum
            pseudofr=linspace(0.02,0.1, 500);
            spectrum=FourierDir(app.tt, interferogram', pseudofr);
            plot(app.UIAxes3, pseudofr, abs(spectrum));


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensity')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Interferogram')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Intensity')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Spectrum')
            xlabel(app.UIAxes3, 'Pseudofrequency')
            ylabel(app.UIAxes3, 'Intensity')
            zlabel(app.UIAxes3, 'Z')
            app.LoadhypercubeButton = uibutton(app.UIFigure, 'push');
            app.LoadhypercubeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadhypercubeButtonPushed, true);
            app.LoadhypercubeButton.Text = 'Load hypercube';
            app.TemporalaxisSliderLabel = uilabel(app.UIFigure);
            app.TemporalaxisSliderLabel.Text = 'Temporal axis';
            app.TemporalaxisSlider = uislider(app.UIFigure, 'range');
            app.LoadeddatasetLabel = uilabel(app.UIFigure);
            app.LoadeddatasetLabel.Text = 'Loaded dataset';
            app.SelectpixelButton = uibutton(app.UIFigure, 'push');
            app.SelectpixelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpixelButtonPushed, true);
            app.SelectpixelButton.Text = 'Select pixel';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y';
            app.DelaycaliabrationCheckBox = uicheckbox(app.UIFigure);
            app.DelaycaliabrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @DelaycaliabrationCheckBoxValueChanged, true);
            app.DelaycaliabrationCheckBox.Text = 'Delay caliabration';
            app.DelaycorrectionLabel = uilabel(app.UIFigure);
            app.DelaycorrectionLabel.Text = 'Delay correction';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Value = 100;
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Value = 100;
            app.SelectpixelButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectpixelButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectpixelButton_2Pushed, true);
            app.SelectpixelButton_2.Text = 'Select pixel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisTempHyp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end